classdef H2_PanelReflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        SaveButton                matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        ExportButton              matlab.ui.control.Button
        LoadDataButton            matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        FitEquationTextArea       matlab.ui.control.TextArea
        FitEquationTextAreaLabel  matlab.ui.control.Label
        RawSwitch                 matlab.ui.control.Switch
        RawSwitchLabel            matlab.ui.control.Label
        AxesLimitsLabel           matlab.ui.control.Label
        UpperYEditField           matlab.ui.control.NumericEditField
        UpperYEditFieldLabel      matlab.ui.control.Label
        LowerYEditField           matlab.ui.control.NumericEditField
        LowerYEditFieldLabel      matlab.ui.control.Label
        ShaderLimitsLabel         matlab.ui.control.Label
        InvertYSwitch             matlab.ui.control.ToggleSwitch
        InvertYLabel              matlab.ui.control.Label
        WavelengthText            matlab.ui.control.Label
        xLabel                    matlab.ui.control.Label
        MaxX                      matlab.ui.control.NumericEditField
        xLabel_2                  matlab.ui.control.Label
        MinX                      matlab.ui.control.NumericEditField
        PrevButton                matlab.ui.control.Button
        NextButton                matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        Path % Store path to current directory
        Index % Store Index from SpectraBuilderApp
        LinData % Store the LinData table passed to this app
        bothY % Store Raw and Processed traces of ydata
        LogData % Store the LogData table passed to this app
        Time % Store the orginal X data
        LogTimeArray % Store the Log Array of the orginal X data
        InititalIndex % Specifiy the index of when the app started to return to
        plot_wavelengths % Store the names of the headers
        IndexSize % Store the size of KinInd
        YData % Store the linear YData from table
        XData % Store the uniform log Xdata
        xPoints % Store the x-data points of ROI object
        X1 % Store the 1st ROI line
        X2 % Store the 2nd ROI line
        Shade % Store shade array
        ShadeArea % Store shade area struct
        FitResult % Store the fit struct
        xData % Store prepared Xdata
        yData % Store prepared Ydata
        yFitData % Store fitted Ydata
        XPointsROI % Store ROI points in cell array
        XMin % Store minimum of uniform Xdata
        XMax % Store maximum of uniform Xdata
        Curve % Store fit plot
        Data % Store scatter plot
        LogPlot % Store plot variable only when log data is loaded in
        tempY % Temp Y variable for invert
        tempX % Temp x variable for invert
        Start % Store if first time setup
        Text % Store alpha variable in text object (label)
        leftX % Store left area text object (label)
        rightX % Store right area text object (label)
        CenterY % Hold the center of all the data for labels and such
    end

    methods (Access = private)
        function [fitresult, gof] = createFit(app)
            %% Fit: 'Log-Log Graph' w/ check for inverse fit data
            [app.xData, app.yData] = prepareCurveData(app.XData, app.YData);

            % Set up fittype and options.
            ft = fittype( 'poly1' );
            excludedPoints = (app.xData < app.xPoints(1,1)) | (app.yData > app.xPoints(1,2));
            opts = fitoptions( 'Method', 'LinearLeastSquares' );
            opts.Robust = 'Bisquare';
            opts.Exclude = excludedPoints;

            % Fit model to data.
            [fitresult, gof] = fit(app.xData, app.yData, ft, opts );
            app.yFitData = feval(fitresult, app.xData);

            % Supply UI data
            fchar = evalc('fitresult');
            fstring = convertCharsToStrings(fchar);
            app.FitEquationTextArea.Value = fstring;
            app.FitResult = fitresult;
        end

        function shader(app, ~, ~)
            % If called without callback execute default
            if nargin == 1
                app.Shade = [];
                app.xPoints = [];

                if ~isempty(app.UIAxes.Children)
                    % Change shaded area accordingly
                    % Store new Ylimits from button
                    tempY1 = app.UIAxes.YLim(1);
                    tempY2 = app.UIAxes.YLim(2);
    
                    % Clear any existing plots
                    cla(app.UIAxes, 'reset');
    
                    app.UIAxes.YLim(1) = tempY1;
                    app.UIAxes.YLim(2) = tempY2;
                end

                %%%%% Default shaded area drawn/data & model
                app.XMin = min(app.XData);
                app.XMax = max(app.XData);

                YMin = min(app.YData);
                YMax = max(app.YData);

                % Draw both shaded areas up until XLims
                hold(app.UIAxes, 'on');
                RGarea = app.ShadeArea;

                % Create new areas
                app.Shade{1} = area(app.UIAxes, [app.XMin RGarea(1)], [YMin YMin]);
                app.Shade{2} = area(app.UIAxes, [RGarea(2) app.XMax], [YMin YMin]);

                % Set color to be same
%                 set(app.Shade{1},'FaceColor',[0.75 0.75 0.75])
%                 set(app.Shade{2},'FaceColor',[0.75 0.75 0.75])
                app.Shade{1}.FaceColor = [0.75 0.75 0.75];
                app.Shade{2}.FaceColor = [0.75 0.75 0.75];

                % Set default axes boundaries
%                 axis(app.UIAxes, [app.XMin app.XMax app.UIAxes.YLim(1) app.UIAxes.YLim(2)]);
                app.UIAxes.XLim = [app.XMin app.XMax];
                app.UIAxes.YLim = [YMin YMax];
                hold(app.UIAxes, 'off');

                % Store Lower and Upper X coordinates
                app.xPoints(1,1) = RGarea(1);
                app.xPoints(1,2) = RGarea(2);

                % Set basevalue first time around
                app.Shade{1,1}.BaseValue = app.UIAxes.YLim(2);

                %%%%% End of shaded area creation

                createFit(app);
                plotModel(app);
                plotData(app);

                cfit = app.FitResult;
                coeffvals = coeffvalues(cfit);
                alpha = ['\alpha = ', num2str(coeffvals(1), '%.2f')];
                x = app.xPoints(1,2) + 10;

                hold(app.UIAxes, 'on' );
                app.Text = text(app.UIAxes, x, (0.90)*app.UIAxes.YLim(2), alpha, 'FontWeight', 'bold');
                app.leftX = text(app.UIAxes, app.ShadeArea(1,1) - 10, app.CenterY, sprintf('%.2f',app.ShadeArea(1,1)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"right");
                app.rightX = text(app.UIAxes, app.ShadeArea(1,2) + 10, app.CenterY, sprintf('%.2f',app.ShadeArea(1,2)), 'Color', 'k', 'FontWeight',"bold", 'HorizontalAlignment',"left");
                hold (app.UIAxes, 'off');

                % Cell array to keep track of every ROI point position
                app.XPointsROI = [];

                % Implement drawline for either side of the area
                if ~isnan(app.xPoints(1,1)) && ~isnan(app.xPoints(1,2))
                    % FOR LABEL INSTEAD OF TEXT
                    % 'Label',sprintf('%.2f',app.ShadeArea(1,1)), 'LabelAlpha', 0, 'LabelTextColor', 'k'
                    app.X1 = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,1) app.UIAxes.YLim(1)-1; app.xPoints(1,1) app.UIAxes.YLim(2)+1], "Deletable",false,"InteractionsAllowed","translate",'Color', [0 0 0]);
                    app.X2 = images.roi.Line(app.UIAxes, "Position", [app.xPoints(1,2) app.UIAxes.YLim(1)-1; app.xPoints(1,2) app.UIAxes.YLim(2)+1], "Deletable",false,"InteractionsAllowed","translate",'Color', [0 0 0]);

                    % Add callbacks for when point moves
                    addlistener(app.X1, "MovingROI", @(src,event)shader(app,src,event));
                    addlistener(app.X2, "MovingROI", @(src,event)shader(app,src,event));

                    % Store lines in vector
                    app.XPointsROI{1,1} = app.X1;
                    app.XPointsROI{1,2} = app.X2;
                end

                % To retrieve proper wavelengths/positions of observed data
%                 if app.CallingApp.TamOn == 0
%                     app.WavelengthText.Text = "Displaying @" + app.plot_wavelengths(app.Index-1) + "nm";
%                 else
%                     app.WavelengthText.Text = "Displaying " + app.CallingApp.positions(app.Index);
%                 end
%                 app.WavelengthText.Text = "Displaying @" + app.plot_wavelengths(app.Index) + "nm";

            else
                % If ROI area is changed through manual mouse input
                % Replace the position of the shaded area to moved ROI pos
                app.ShadeArea=[app.XPointsROI{1,1}.Position(1),app.XPointsROI{1,2}.Position(1)];

                % Continue as normal
                default(app);
                createFit(app);
                plotModel(app);

                RGarea = app.ShadeArea;

                % Change label values accordingly to the moved x pos
                app.leftX.Position = [app.ShadeArea(1,1) - 10, app.CenterY];
                app.leftX.String = sprintf('%.2f',RGarea(1));
                app.rightX.Position = [app.ShadeArea(1,2) + 10, app.CenterY];
                app.rightX.String = sprintf('%.2f',RGarea(2));

                % Gather updated data for updated annotation
                cfit = app.FitResult;
                coeffvals = coeffvalues(cfit);
                alpha = ['\alpha = ', num2str(coeffvals(1),'%.2f')];
                x = app.X2.Position(2,1)+10;

                app.Text.Position = [x (0.90)*app.UIAxes.YLim(2)];
                app.Text.String = alpha;

                % Pause ever so quickly to update labels
                pause(0.00000000000001);
            end
        end

        function default(app)
            % Update both shaded areas up until XLims
            hold(app.UIAxes, 'on');

            RGarea = app.ShadeArea;

            % Update new areas
            app.Shade{1}.XData = [app.XMin RGarea(1)];
            app.Shade{2}.XData = [RGarea(2) app.XMax];

            hold(app.UIAxes, 'off');

            % Store Lower and Upper X coordinates
            app.xPoints(1,1) = RGarea(1);
            app.xPoints(1,2) = RGarea(2);
        end


        function plotModel(app)
            % Plot the model and its limits
            delete(app.Curve);

            hold(app.UIAxes, 'on' );
            app.Curve = plot(app.UIAxes, app.xData, app.yFitData, 'color', [1 0 0]);
%             app.Curve = plot(app.UIAxes, app.XData, app.yFitData, 'color', [1 0 0]);
            hold (app.UIAxes, 'off');

            app.MinX.Value = app.xPoints(1,1);
            app.MaxX.Value = app.xPoints(1,2);
        end

        function plotData(app)
            % Plot the data as scatter
            delete(app.Data);
            hold(app.UIAxes, 'on' );
            app.Data = scatter(app.UIAxes, app.XData, app.YData, 10, [0 0.4470 0.7410], 'filled');
%             app.Data = line(app.UIAxes, app.XData, app.YData);
            hold (app.UIAxes, 'off');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % Initialize properties
            app.ShadeArea = [];

            %Proper startup to keep app looking clean at first
            app.InvertYSwitch.Value = 'Off';
            app.Start = 1;
            app.Index = 0;

            app.UITable.ColumnFormat = {'logical','numeric','char'};

            %Index variables for which files the GUI is viewing
            %             if ~app.CallingApp.IsLogDataOnly
            %                 app.IndexSize = width(app.LogData);
            %             else
            %                 app.IndexSize = width(app.CallingApp.Table);
            %             end

            %             app.Index = app.Index + 1;
            %             if ~app.CallingApp.IsLogDataOnly
            % Get proper input values for second app
            % Log of the Log-Spaced Linear loaded Data
            %                 app.YData = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
            %                 app.XData = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));

            % Log of the Lin-Spaced Linear Data. No longer used
            %LogLinABS = real(log10(app.LinData{:,KinInd}(app.Time>0)));
            %LogLinTime = real(log10(app.LinData{:,1}(app.Time>0)));
            %             else
            %                 % Log of the Log-Spaced loaded Data
            %                 timeRG = table2array(app.CallingApp.Table(:,1));
            %                 app.YData = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
            %                 app.XData = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            %             end

            % Record a starting range for shaded area on startup
            %             if ~app.CallingApp.IsLogDataOnly
            %                 default_shade = real(log10(app.LogData{:,2}(app.LogTimeArray>0)));
            %             else
            %                 timeRG = table2array(app.CallingApp.Table(:,1));
            %                 default_shade = real(log10(app.CallingApp.Table{:,2}(timeRG>0)));
            %             end

            % Use the starting range to update ShadedArea
            %             CenterTime = mean(default_shade);
            %
            %             if CenterTime < 0
            %                 app.ShadeArea = [CenterTime-1 CenterTime+1];
            %             else
            %                 app.ShadeArea = [CenterTime+1 CenterTime-1];
            %             end
            %
            %             shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'H2 Fitting');
            ylabel(app.UIAxes,'μmol/L or mV');
            xlabel(app.UIAxes,'Time (s)');
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile({'*.xlsx';'*.csv';'*.xlsm';'*.xls';'*.slx';'*.*'},'File Selector');
            filevar = fullfile(path,file);
            app.Path = path;

            if file == 0
                return;
            else
                % H2Table = readtable(filevar); For full table
                % Or just use options to select specific columns
                opts = detectImportOptions(filevar);
                opts.SelectedVariableNames = opts.SelectedVariableNames([3,4,5]); % Only useful x and y data`
                opts.VariableNamingRule = "preserve";

                app.LinData = readtable(filevar,opts);

                app.XData = table2array(app.LinData(:,1));
                app.bothY = table2array(app.LinData(:,2:3)); % COLLECT RAW AND PROCESSED. FIGURE OUT HOW TO DISTINGUISH EFFICIENTLY

                if ~all(app.bothY(:,2))
                    app.YData = app.bothY(:,1);
                    app.RawSwitch.Value = "On";
                else
                    app.YData = app.bothY(:,2);
                    app.RawSwitch.Value = "Off";
                end

                app.IndexSize = width(app.LinData);
                app.Index = app.Index + 1;

                app.CenterY = mean(app.YData);

%                 CenterTime = mean(default_shade);
                CenterTime = mean(app.XData);

%                 mag = floor(log(abs(CenterTime))./log(10));

                if CenterTime > 0
                    app.ShadeArea = [CenterTime/10 CenterTime+100];
                else
                    app.ShadeArea = [CenterTime+100 CenterTime/10];
                end

                shader(app);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Store necessary variables for export
            time = app.XData;
            abs = app.YData;
            yfit = app.yFitData;
            fitwindow = [app.xPoints(1,1); app.xPoints(1,2)];
            fiteqn = [app.FitResult.p1; app.FitResult.p2];

            % Create blank array with titles
            p = {'LogTime', 'LogABS', 'yfitData', 'fitWindow', 'fitEquation'};
            A = nan(size(time,1),5);
            A(1:length(time),1) = time;
            A(1:length(abs),2) = abs;
            A(1:length(yfit),3) = yfit;
            A(1:length(fitwindow),4) = fitwindow;
            A(1:length(fiteqn),5) = fiteqn;

            % Convert 5 column vectors into table with appropriate names
            T = array2table(A);
            T.Properties.VariableNames(1:5) = p;

            % Add wavelength into title of filename
%             f = sprintf('H2LinData%s.csv',app.WavelengthText.Text);
            f = 'H2LinData.csv';
            place = fullfile(app.CallingApp.Path,f);
            writetable(T,place);
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
            % Reset inverse switch and go to prev data index
            app.InvertYSwitch.Value = 'Off';
            app.Index = app.Index - 1;
            if app.Index < 2
                app.Index = 2;
            end

            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear Data
                app.YData = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.XData = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));
            else
                % Loaded LogData
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.YData = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.XData = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'H2 Fitting');
            ylabel(app.UIAxes,'μmol/L or mV');
            xlabel(app.UIAxes,'Time (s)');
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Reset inverse switch and go to next data index
            app.InvertYSwitch.Value = 'Off';
            app.Index = app.Index + 1;
            if app.Index > app.IndexSize
                app.Index = app.IndexSize;
            end

            if ~app.CallingApp.IsLogDataOnly
                % Get proper input values for second app
                % Log of the Log-Spaced Linear Data
                app.YData = real(log10(app.LogData{:,app.Index}(app.LogTimeArray>0)));
                app.XData = real(log10(app.LogData{:,1}(app.LogTimeArray>0)));
            else
                % Loaded LogData
                timeRG = table2array(app.CallingApp.Table(:,1));
                app.YData = real(log10(app.CallingApp.Table{:,app.Index}(timeRG>0)));
                app.XData = real(log10(app.CallingApp.Table{:,1}(timeRG>0)));
            end

            shader(app);

            % Add labels separately to view title, xlabel, and ylabel
            title(app.UIAxes, 'H2 Fitting');
            ylabel(app.UIAxes,'μmol/L or mV');
            xlabel(app.UIAxes,'Time (s)');
        end

        % Value changed function: MinX
        function MinXValueChanged(app, event)
%             value = app.MinX.Value;
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue < app.XMin)
                latestvalue = app.XMin; %#ok<NASGU>
            elseif (latestvalue > app.X2.Position(1))
                latestvalue = app.X2.Position(1) + 1; %#ok<NASGU>
            else
                % Code to snap to nearest data point
                app.xPoints(1,1) = latestvalue;
                tst = abs(app.XData - latestvalue);
                [~, line_index] = min(abs(app.XData - latestvalue));
                closest = app.XData(line_index);

                app.X1.Position = [closest -11; closest 1];
                app.X1.Label = sprintf('%.6f',closest);

                app.ShadeArea = [closest app.xPoints(1,2)];


                default(app);
                createFit(app);
                plotModel(app);
            end
        end

        % Value changed function: MaxX
        function MaxXValueChanged(app, event)
%             value = app.MaxX.Value;
            latestvalue = event.Value;

            % Keep the changed input within the boundary conditons
            if (latestvalue > app.XMax)
                latestvalue = app.XMax; %#ok<NASGU>
            elseif (latestvalue < app.X1.Position(1))
                latestvalue = app.X1.Position(1) - 1; %#ok<NASGU>
            else
                % Code to snap to nearest data point
                app.xPoints(1,2) = latestvalue;

                [~, line_index] = min(abs(app.XData - latestvalue));
                closest = app.XData(line_index);

                app.X2.Position = [closest -11; closest 1];
                app.X2.Label = sprintf('%.6f',closest);

                app.ShadeArea = [app.xPoints(1,1) closest];

                default(app);
                createFit(app);
                plotModel(app);
            end
        end

        % Value changed function: InvertYSwitch
        function InvertYSwitchValueChanged(app, event)
%             value = app.InvertYSwitch.Value;
            % Check the event value and proceed accordingly if inverted
            value = convertCharsToStrings(event.Value);

%             if ~app.CallingApp.IsLogDataOnly
                if (value == 'On') %#ok<BDSCA>
                    % Copy temp variables for when you switch back
                    app.tempY = app.YData;
                    app.tempX = app.XData;

                    % Turn on and change data to inverted
                    inverseLin = (app.LinData{:,app.Index}).*(-1);
                    app.YData = inverseLin;
%                     app.YData = real(log10(inverseLog(app.LogTimeArray<0)));
%                     app.XData = real(log10(app.LogData{:,1}(app.LogTimeArray<0)));

                    createFit(app);
                    plotModel(app);
                    plotData(app);
                    ylabel(app.UIAxes,'-μmol/L or -mV');
                else
                    % Turn back off and return to normal data
                    app.YData = app.tempY;
                    app.XData = app.tempX;

                    createFit(app);
                    plotModel(app);
                    plotData(app);
                    ylabel(app.UIAxes,'μmol/L or mV');
                end
%             else
%                 timeRG = table2array(app.CallingApp.Table(:,1));
%                 if (value == 'On') %#ok<BDSCA>
%                     % Copy temp variables for when you switch back
%                     app.tempY = app.YData;
%                     app.tempX = app.XData;
% 
%                     % Turn on and change loaded log data to inverted
%                     inverseLog = (app.CallingApp.Table{:,app.Index}).*(-1);
%                     app.YData = real(log10(inverseLog(timeRG<0)));
%                     app.XData = real(log10(app.CallingApp.Table{:,1}(timeRG<0)));
% 
%                     createFit(app);
%                     plotModel(app);
%                     plotData(app);
%                     ylabel(app.UIAxes,'log(-ABS)');
%                 else
%                     % Turn back off and return to normal loaded log data
%                     app.YData = app.tempY;
%                     app.XData = app.tempX;
% 
%                     createFit(app);
%                     plotModel(app);
%                     plotData(app);
%                     ylabel(app.UIAxes,'log(ABS)');
%                 end
%             end
        end

        % Value changed function: LowerYEditField
        function LowerYEditFieldValueChanged(app, event)
            value = app.LowerYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(1) = value;
            end
        end

        % Value changed function: UpperYEditField
        function UpperYEditFieldValueChanged(app, event)
            value = app.UpperYEditField.Value;
            if isempty(value)
                return;
            else
                app.UIAxes.YLim(2) = value;
            end
        end

        % Value changed function: RawSwitch
        function RawSwitchValueChanged(app, event)
            value = app.RawSwitch.Value;

            if value == "Off" && all(app.bothY(:,2))
                app.YData = app.bothY(:,2);
            else
                app.YData = app.bothY(:,1);
            end

            app.Data.YData = app.YData;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {516, 516};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {231, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'H2 Fitting';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.LoadDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ExportButton = uibutton(app.GridLayout2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.UITable = uitable(app.GridLayout2);
            app.UITable.ColumnName = {'✓'; 'Save'; 'Slope'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false false];
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'H2 Fitting')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'μmol/L or mV')
            zlabel(app.UIAxes, 'Z')
            app.NextButton = uibutton(app.RightPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.RightPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.MinX = uieditfield(app.RightPanel, 'numeric');
            app.MinX.ValueChangedFcn = createCallbackFcn(app, @MinXValueChanged, true);
            app.xLabel_2 = uilabel(app.RightPanel);
            app.xLabel_2.Text = 'Lower X';
            app.MaxX = uieditfield(app.RightPanel, 'numeric');
            app.MaxX.ValueChangedFcn = createCallbackFcn(app, @MaxXValueChanged, true);
            app.xLabel = uilabel(app.RightPanel);
            app.xLabel.Text = 'Upper X';
            app.WavelengthText = uilabel(app.RightPanel);
            app.WavelengthText.Text = '';
            app.InvertYLabel = uilabel(app.RightPanel);
            app.InvertYLabel.Text = 'Invert Y';
            app.InvertYSwitch = uiswitch(app.RightPanel, 'toggle');
            app.InvertYSwitch.ValueChangedFcn = createCallbackFcn(app, @InvertYSwitchValueChanged, true);
            app.ShaderLimitsLabel = uilabel(app.RightPanel);
            app.ShaderLimitsLabel.Text = 'Shader Limits';
            app.LowerYEditFieldLabel = uilabel(app.RightPanel);
            app.LowerYEditFieldLabel.Text = 'Lower Y';
            app.LowerYEditField = uieditfield(app.RightPanel, 'numeric');
            app.LowerYEditField.ValueChangedFcn = createCallbackFcn(app, @LowerYEditFieldValueChanged, true);
            app.UpperYEditFieldLabel = uilabel(app.RightPanel);
            app.UpperYEditFieldLabel.Text = 'Upper Y';
            app.UpperYEditField = uieditfield(app.RightPanel, 'numeric');
            app.UpperYEditField.ValueChangedFcn = createCallbackFcn(app, @UpperYEditFieldValueChanged, true);
            app.AxesLimitsLabel = uilabel(app.RightPanel);
            app.AxesLimitsLabel.Text = 'Axes Limits';
            app.RawSwitchLabel = uilabel(app.RightPanel);
            app.RawSwitchLabel.Text = 'Raw Switch';
            app.RawSwitch = uiswitch(app.RightPanel, 'slider');
            app.RawSwitch.ValueChangedFcn = createCallbackFcn(app, @RawSwitchValueChanged, true);
            app.FitEquationTextAreaLabel = uilabel(app.RightPanel);
            app.FitEquationTextAreaLabel.Text = 'Fit Equation:';
            app.FitEquationTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = H2_PanelReflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end